classdef base_model_MAC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartSimulationButton        matlab.ui.control.Button
        NumberofNodesEditFieldLabel  matlab.ui.control.Label
        NumberofNodesEditField       matlab.ui.control.NumericEditField
        CreateNodesButton            matlab.ui.control.Button
        ExecFunctionCommandWindowButton  matlab.ui.control.Button
        StopSimulationButton         matlab.ui.control.Button
        RangeSliderLabel             matlab.ui.control.Label
        RangeSlider                  matlab.ui.control.Slider
        RemoveNodesButton            matlab.ui.control.Button
        SendDataButton               matlab.ui.control.Button
        AmountJobs0Label             matlab.ui.control.Label
        NoCollisionLabel             matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Retransmissions0Label        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    % Global variables, call them by prepending 'app.' in front of them
    properties (Access = private)
        NodesLocationArray % Array which contains the coordinates of each node
        SimulationSpeed = 1 % Speed at which the signal moves in terms of seconds [x]
        
        %This is a job array where index one is the coordinate of the node
        %and index 2 gives the cirkelcnt of the node, in total there are
        %1000 jobs available when this is reached it should start from the
        %beginning, jobStart gives the first job and jobend gives the last
        %job
        TransmissionArray = zeros(2,1000);
        Jobstart = 1;
        Jobend = 1;
        AmountOfJobs;
                
        %CollisionArray where all the sending nodes are stored. The radius
        %and coordinates are compared to determine collision
        collisionArray 
        
        %Array that is used to retransmission nodes that were collided, the
        %first gives the coordinate of the node and the second the
        %retransmission delay count
        RetransmissionArray = zeros(2,1000);
        AmountOfRetr = 0;
        
        %Different parameters for the model
        LOADAMOUNT = 1;    
        AMOUNTOFNODES = 0;
        STARTSIMULATION = false;
        NODERANGE = 5;        
    end
    
    methods (Access = private)
        
        % This is a custom function that you can use (NOT USED YET WAS IN BASE CODE!)
        function customFunction(app, par1)
            disp(['The simulation speed is set at: ', num2str(app.SimulationSpeed), ' seconds per frame'])

            % Variables are not stored in the workspace by default (can use debug mode to show it)
            % Or use the function assignin (should declare it locally first before assignin works)            
            if (par1 == true)
                simspeed = app.SimulationSpeed;            
                assignin('base', 'savedVar_simspeed', simspeed);
            end
        end
    end
    
     methods (Access = public)
         %This function is called for every cirkel that needs to be drawn
         %nodeNumber is the node that is sending, cirkelcount is the drawn
         %cirkel, count from inwards till outwards (max is app.NODERANGE)
         %NodesLocationArray(nodeNumber,1) and
         %NodesLocationArray(nodeNumber,2) are x, y coordinates from the
         %node
         function plotSending(app, nodeNumber, cirkelCount, collIndex)
            theta = linspace(0,2*pi,100);
            radius = 0.1; % Hop for each iteration [x]
            
            hold(app.UIAxes, 'on') % Ensures all previous plots (nodes) will remain on the axes
            
            %Plot the cirkel that stands for transmitting
            plot(app.UIAxes, app.NodesLocationArray(nodeNumber,1)+radius*cirkelCount*cos(theta),app.NodesLocationArray(nodeNumber,2)+radius*cirkelCount*sin(theta), '-k'); 
            
            %add the x,y,r parameters from the node to the collisionArray
            app.collisionArray(collIndex,1) = app.NodesLocationArray(nodeNumber,1);
            app.collisionArray(collIndex,2) = app.NodesLocationArray(nodeNumber,2);
            app.collisionArray(collIndex,3) = radius*cirkelCount;
            app.collisionArray(collIndex,4) = nodeNumber;
            
            %pause(app.SimulationSpeed) % Create the propagating effect
            %cla(app.UIAxes) % Clears the whole axes when new nodes are created
            %plot(app.UIAxes, app.NodesLocationArray(:,1), app.NodesLocationArray(:,2), 'ok', 'MarkerfaceColor', 'k');            
         end
         
         %Function that will check all the sending nodes on collision
         function collisionCalculation(app, collindex)
             for i = 1:collindex
                 xCoordinate1 = app.collisionArray(i,1);
                 yCoordinate1 = app.collisionArray(i,2);
                 rCoordinate1 = app.collisionArray(i,3);
                 nodenumber1 = app.collisionArray(i,4);
                                  
                 for ind = 1:collindex
                    if(ind ~= i)
                        x = xCoordinate1 - app.collisionArray(ind, 1);
                        y = yCoordinate1 - app.collisionArray(ind, 2);               
                        distance = sqrt((x * x) + (y * y));                     
                        r = rCoordinate1 + app.collisionArray(ind, 3);
                        nodenumber2 = app.collisionArray(ind,4);
                        
                        %If the distance is smaller than the summ of the
                        %radi then there is collision, both nodes will be
                        %added to the retransmission queue with random (delay)
                        %retransmission time counter 
                        if(distance <= r)
                            if(ismember(nodenumber1, app.RetransmissionArray) == false)
                                app.RetransmissionArray(1,nodenumber1) = nodenumber1;                           %add node to retransmission array
                                app.RetransmissionArray(2,nodenumber1) = round(rand() * 20 + 1);                %add random delay
                                
                                app.AmountOfRetr = app.AmountOfRetr + 1;                                        %increase amount of to be retransmissioned packets
                                textLabel = sprintf('Retransmissions: %d', app.AmountOfRetr);
                                app.Retransmissions0Label.Text = textLabel;
                                
                                plot(app.UIAxes, app.NodesLocationArray(nodenumber1,1), app.NodesLocationArray(nodenumber1,2), 'ok', 'MarkerfaceColor', 'r'); 
                            end
                            
                            if(ismember(nodenumber2, app.RetransmissionArray) == false)
                                app.RetransmissionArray(1,nodenumber2) = nodenumber2;
                                app.RetransmissionArray(2,nodenumber2) = round(rand() * 20 + app.NODERANGE);
                                
                                app.AmountOfRetr = app.AmountOfRetr + 1;
                                textLabel = sprintf('Retransmissions: %d', app.AmountOfRetr);
                                app.Retransmissions0Label.Text = textLabel;
                                
                                plot(app.UIAxes, app.NodesLocationArray(nodenumber2,1), app.NodesLocationArray(nodenumber2,2), 'ok', 'MarkerfaceColor', 'r');
                            end
                        
                            app.NoCollisionLabel.Text = 'Collision!!';
                            drawnow
                        end  
                    end 
                 end    
             end     
         end
         
         %function to add the retransmission items back to the sending
         %items when their random wait time is over
         function addRetransmissionToJobList(app)
             for i = 1:app.AMOUNTOFNODES
                if(app.RetransmissionArray(1,i) ~= 0)
                    app.RetransmissionArray(2,i) = app.RetransmissionArray(2,i) - 1;
                    
                    %Check if counter is 0 and thus needs to be added
                    if(app.RetransmissionArray(2,i) == 0)
                        app.Jobend = app.Jobend + 1;
                        app.TransmissionArray(1, app.Jobend) = app.RetransmissionArray(1,i);    %Add to the sending list
                        app.TransmissionArray(2, app.Jobend) = 1;                               %Cirkelcount starts always at 1 
                        
                        %remove node from retransmissionarray
                        app.RetransmissionArray(1, i) = 0;                                      %reset elements(nodeumber and Cirkelcount) to 0 so its "removed"
                        app.RetransmissionArray(2, i) = 0;
                        app.AmountOfRetr = app.AmountOfRetr - 1;
                        
                        textLabel = sprintf('Retransmissions: %d', app.AmountOfRetr);
                        app.Retransmissions0Label.Text = textLabel;
                    end       
                end
             end
         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Input stuff that you want to execute at startup
            disp('App has started up!') % Will display on the main MATLAB command window    
            xlim(app.UIAxes, [0 1]);
            ylim(app.UIAxes, [0 1]);
        end

        % Button pushed function: CreateNodesButton
        function CreateNodesButtonPushed(app, event)
            cla(app.UIAxes) % Clears the whole axes when new nodes are created
            app.AMOUNTOFNODES = app.NumberofNodesEditField.Value; % Number of nodes, input from user
            
            app.NodesLocationArray = rand(app.AMOUNTOFNODES,2); % Creates random N x 2 array [2: (X,Y) values]
            
            % Plot nodes as small circles
            plot(app.UIAxes, app.NodesLocationArray(:,1), app.NodesLocationArray(:,2), 'ok', 'MarkerfaceColor', 'k');
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.STARTSIMULATION = true;
            app.Label.Text = 'Simulation is running!';
        while(app.STARTSIMULATION) 
            collindex = 1;
            %create an empty array with x,y,r coordinates
            app.collisionArray = zeros(app.Jobend - app.Jobstart + 1, 4); 
            
            %Check if there are new retransmissions that need to be added
            %to the transmissionlist because they waited random time
            addRetransmissionToJobList(app);
            
            for i = app.Jobstart:app.Jobend
                
                if(app.TransmissionArray(1,i) ~= 0)
                    plotSending(app, app.TransmissionArray(1,i), app.TransmissionArray(2,i), collindex); 
                    collindex = collindex + 1;
                end
                                
                %If the cirkelcount is equal to the maximal range the
                %node did its transmission and should be removed from the
                %job list
                if(app.TransmissionArray(2,i) >= app.NODERANGE)
                    app.TransmissionArray(1,i) = 0;
                    app.TransmissionArray(2,i) = 0;
                    app.Jobstart = app.Jobstart + 1;
                    
                    %Update the amount of Jobs
                    Jobs = app.Jobend - app.Jobstart + 1;
                    textLabel = sprintf('Amount Jobs: %d', Jobs);
                    app.AmountJobs0Label.Text = textLabel;
                    drawnow
                end
                
                %If the cirkelcount is not equal to the max range,
                %increment it with 1
                if(app.TransmissionArray(2,i) < app.NODERANGE)
                    app.TransmissionArray(2,i) = app.TransmissionArray(2,i) + 1;
                end
            end
           
            %All the nodes have their cirkels, now the collision can be
            %calculated
            if(app.Jobend - app.Jobstart ~= 0)
                collisionCalculation(app, collindex - 1);
            end
                
            pause(app.SimulationSpeed) % Create the propagating effect
            
            app.NoCollisionLabel.Text = 'No Collision';
            drawnow
            
            cla(app.UIAxes);
            plot(app.UIAxes, app.NodesLocationArray(:,1), app.NodesLocationArray(:,2), 'ok', 'MarkerfaceColor', 'k');
        end
            
        end

        % Button pushed function: ExecFunctionCommandWindowButton
        function ExecFunctionCommandWindowButtonPushed(app, event)
            customFunction(app, true); % Executes our custom function
        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            app.Label.Text = '';
            app.STARTSIMULATION = false;
            app.TransmissionArray = zeros(2,1000);
            app.collisionArray = zeros(app.Jobend - app.Jobstart + 1, 3); 
            app.Jobstart = 1;
            app.Jobend = 1;
        end

        % Callback function
        function LoadSliderValueChanged(app, event)
            value = app.LoadSlider.Value;
            app.LOADAMOUNT = round(app.AMOUNTOFNODES * value/100);
        end

        % Button pushed function: RemoveNodesButton
        function RemoveNodesButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            value = app.RangeSlider.Value;
            app.NODERANGE = 10 * value/100;
        end

        % Button pushed function: SendDataButton
        function SendDataButtonPushed(app, event)
            
            %Assign random node coordinate to the joblist and increment
            %Jobend to add more jobs to the end of the list, set the
            %cirkelcount to 0
            app.Jobend = app.Jobend + 1;
            newJob = 1;
            
            %Try to make a new job that is not in the queue yet 
            for i = 1:(app.AMOUNTOFNODES*2)
                newJob = round(rand * app.AMOUNTOFNODES -1) + 1;
                if(ismember(newJob, app.TransmissionArray) == false)
                    break   
                end
            end

            app.TransmissionArray(1, app.Jobend) = newJob;
            app.TransmissionArray(2, app.Jobend) = 1;
                        
            %Update the amount of Jobs, due to a (unknown) bug the first one does not 
            %add 1 to the jobs
            if(app.Jobend <= 2)
                Jobs = app.Jobend - app.Jobstart;
                textLabel = sprintf('Amount Jobs: %d', Jobs);
                app.AmountJobs0Label.Text = textLabel;
                drawnow
            end
            
            if(app.Jobend > 2)
                Jobs = app.Jobend - app.Jobstart + 1;
                textLabel = sprintf('Amount Jobs: %d', Jobs);
                app.AmountJobs0Label.Text = textLabel;
                drawnow     
            end

            
            %disp('New job:');
            %disp(app.TransmissionArray(1, app.Jobend));
            %disp(app.TransmissionArray(2, app.Jobend));
            
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.NumberofNodesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNodesEditField.Value = 10;
            app.CreateNodesButton = uibutton(app.UIFigure, 'push');
            app.CreateNodesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNodesButtonPushed, true);
            app.CreateNodesButton.Text = 'Create Nodes';
            app.ExecFunctionCommandWindowButton = uibutton(app.UIFigure, 'push');
            app.ExecFunctionCommandWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExecFunctionCommandWindowButtonPushed, true);
            app.ExecFunctionCommandWindowButton.Text = {'Exec Function'; 'Command'; 'Window'};
            app.StopSimulationButton = uibutton(app.UIFigure, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.RangeSliderLabel = uilabel(app.UIFigure);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.UIFigure);
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeSlider.Value = 50;
            app.RemoveNodesButton = uibutton(app.UIFigure, 'push');
            app.RemoveNodesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNodesButtonPushed, true);
            app.RemoveNodesButton.Text = 'Remove Nodes';
            app.SendDataButton = uibutton(app.UIFigure, 'push');
            app.SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @SendDataButtonPushed, true);
            app.SendDataButton.Text = 'Send Data';
            app.AmountJobs0Label = uilabel(app.UIFigure);
            app.AmountJobs0Label.Text = 'Amount Jobs: 0';
            app.NoCollisionLabel = uilabel(app.UIFigure);
            app.NoCollisionLabel.Text = 'No Collision';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Retransmissions0Label = uilabel(app.UIFigure);
            app.Retransmissions0Label.Text = 'Retransmissions: 0';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'MAC protocol '; 'simulation'})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = base_model_MAC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end